classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Label          matlab.ui.control.Label
        Slider         matlab.ui.control.Slider
        UIAxes         matlab.ui.control.UIAxes
        Label_2        matlab.ui.control.Label
        Slider_2       matlab.ui.control.Slider
        Slider_3Label  matlab.ui.control.Label
        Slider_3       matlab.ui.control.Slider
    end

    methods (Access = private)
        
        function DrawAxes(app)
            global Ax Ay Bx By Cx Cy;
            global c;
            global B;
            
            % 畫圓
            theta = 0: 0.01: 2*pi;
            x = Bx + c*cos(theta);
            y = By + c*sin(theta);
            plot(app.UIAxes, x, y);
            hold(app.UIAxes, "on");
            
            % 畫點A
            plot(app.UIAxes, Ax, Ay, 'o');
            
            % 畫點B
            plot(app.UIAxes, Bx, By, 'o');
            
            % 畫點C
            plot(app.UIAxes, Cx, Cy, 'o');
            
            % 畫點D
            Dx = Bx + c*cos(B+pi);
            Dy = By + c*sin(B+pi);
            plot(app.UIAxes, Dx, Dy, 'o');
            hold(app.UIAxes, "on");
            
            % 畫線a
            plot(app.UIAxes, [Cx Ax], [Cy Ay]);
            hold(app.UIAxes, "on");
            
            % 畫線b
            plot(app.UIAxes, [Dx Cx], [Dy Cy]);
            hold(app.UIAxes, "on");
            
            % 畫線c
            plot(app.UIAxes, [Bx Dx], [By Dy]);
            hold(app.UIAxes, "off");
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 頂點
            global Ax Ay Bx By Cx Cy;
            Ax = 0;
            Ay = 5;
            Bx = 5;
            By = 5;
            Cx = 2.5;
            Cy = 5;
            
            % 邊長
            global b c d;
            c = 2.5;
            d = 2.5;
            b = 0;
            
            % 角度
            global B;
            B = 0;
            
            DrawAxes(app)
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            % 滑塊位置
        
            global Bx Cx;
            global b c d;
            global B;

            Cx = event.Value;

            d = Bx - Cx;
            
            B = acos( (b^2-d^2-c^2) / (-2*d*c) );

            DrawAxes(app);
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            global b c d;
            global B;
            
            % 曲柄長
            c = event.Value;
            
            B = acos( (b^2-d^2-c^2) / (-2*d*c) );
            
            DrawAxes(app);
        end

        % Value changing function: Slider_3
        function Slider_3ValueChanging(app, event)
            global b c d;
            global B;
            
            % 浮桿長
            b = event.Value;
            
            B = acos( (b^2-d^2-c^2) / (-2*d*c) );
            
            DrawAxes(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '滑塊位置';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 5];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 2.5;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-1 11];
            app.UIAxes.YLim = [-1 11];
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '曲柄長';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [0 5];
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_2.Value = 2.5;
            app.Slider_3Label = uilabel(app.UIFigure);
            app.Slider_3Label.Text = '浮桿長';
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [0 5];
            app.Slider_3.ValueChangingFcn = createCallbackFcn(app, @Slider_3ValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end